classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        CHECKEditField        matlab.ui.control.NumericEditField
        CHECKEditFieldLabel   matlab.ui.control.Label
        PlotButton            matlab.ui.control.Button
        VelocityPanel         matlab.ui.container.Panel
        VzmsEditField         matlab.ui.control.NumericEditField
        VzmsLabel             matlab.ui.control.Label
        VxmsEditField         matlab.ui.control.NumericEditField
        VxmsLabel             matlab.ui.control.Label
        VymsEditField         matlab.ui.control.NumericEditField
        VymsLabel             matlab.ui.control.Label
        OmegaPanel            matlab.ui.container.Panel
        ValueradsEditField    matlab.ui.control.NumericEditField
        ValueradsLabel        matlab.ui.control.Label
        PhidegreeEditField    matlab.ui.control.NumericEditField
        PhidegreeLabel        matlab.ui.control.Label
        ThetadegreeEditField  matlab.ui.control.NumericEditField
        ThetadegreeLabel      matlab.ui.control.Label
        TimePanel             matlab.ui.container.Panel
        StepsEditField        matlab.ui.control.NumericEditField
        StepsEditFieldLabel   matlab.ui.control.Label
        EndsEditField         matlab.ui.control.NumericEditField
        EndsEditFieldLabel    matlab.ui.control.Label
        BeginsEditField       matlab.ui.control.NumericEditField
        BeginsEditFieldLabel  matlab.ui.control.Label
        UIAxes                matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            x0 = 0;
            y0 = 0;
            z0 = 1;
            vx0 = app.VxmsEditField.Value;
            vy0 = app.VymsEditField.Value;
            vz0 = app.VzmsEditField.Value;
            xyz0 = [x0;vx0;y0;vy0;z0;vz0];
            
            omega = app.ValueradsEditField.Value;
            theta = app.ThetadegreeEditField.Value;
            phi = app.PhidegreeEditField.Value;
 
            omega_x = omega * sind(theta) * cosd(phi);
            omega_y = omega * sind(theta) * sind(phi);
            omega_z = omega * cosd(theta);
            omega_arr = [omega_x omega_y omega_z];
            
            timeBegin = app.BeginsEditField.Value;
            timeEnd = app.EndsEditField.Value;
            timeStep = app.StepsEditField.Value;
            times = timeBegin:timeStep:timeEnd;
           [t, xyz] = ode45(@(t,xyz)magnusApp(t,xyz,omega_arr), times, xyz0);
           X_array=xyz(:,1);
           Y_array=xyz(:,3);
           Z_array=xyz(:,5);
           Time=1;
           step=(timeEnd-timeBegin)/timeStep+1;
           while Time<step
               scatter3(X_array(Time), Y_array(Time), Z_array(Time))
               hold on;
               pause(3);
               app.CHECKEditField.Value=(Time-1)*timeStep;
               Time=Time+1;
           end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Trajactory of ball')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.TimePanel = uipanel(app.UIFigure);
            app.TimePanel.Title = 'Time';
            app.BeginsEditFieldLabel = uilabel(app.TimePanel);
            app.BeginsEditFieldLabel.Text = {'Begin (s)'; ''};
            app.BeginsEditField = uieditfield(app.TimePanel, 'numeric');
            app.BeginsEditField.Limits = [0 50];
            app.EndsEditFieldLabel = uilabel(app.TimePanel);
            app.EndsEditFieldLabel.Text = {'End (s)'; ''};
            app.EndsEditField = uieditfield(app.TimePanel, 'numeric');
            app.EndsEditField.Limits = [0 50];
            app.EndsEditField.Value = 6;
            app.StepsEditFieldLabel = uilabel(app.TimePanel);
            app.StepsEditFieldLabel.Text = {'Step (s)'; ''};
            app.StepsEditField = uieditfield(app.TimePanel, 'numeric');
            app.StepsEditField.Limits = [0 50];
            app.StepsEditField.Value = 0.05;
            app.OmegaPanel = uipanel(app.UIFigure);
            app.OmegaPanel.Title = 'Omega';
            app.ThetadegreeLabel = uilabel(app.OmegaPanel);
            app.ThetadegreeLabel.Text = 'Theta (degree)';
            app.ThetadegreeEditField = uieditfield(app.OmegaPanel, 'numeric');
            app.ThetadegreeEditField.Limits = [-90 90];
            app.PhidegreeLabel = uilabel(app.OmegaPanel);
            app.PhidegreeLabel.Text = 'Phi (degree)';
            app.PhidegreeEditField = uieditfield(app.OmegaPanel, 'numeric');
            app.PhidegreeEditField.Limits = [0 360];
            app.PhidegreeEditField.Value = 180;
            app.ValueradsLabel = uilabel(app.OmegaPanel);
            app.ValueradsLabel.Text = 'Value (rad/s)';
            app.ValueradsEditField = uieditfield(app.OmegaPanel, 'numeric');
            app.ValueradsEditField.Limits = [0 100];
            app.ValueradsEditField.Value = 15;
            app.VelocityPanel = uipanel(app.UIFigure);
            app.VelocityPanel.Title = 'Velocity';
            app.VymsLabel = uilabel(app.VelocityPanel);
            app.VymsLabel.Text = 'Vy (m/s)';
            app.VymsEditField = uieditfield(app.VelocityPanel, 'numeric');
            app.VymsEditField.Limits = [0 50];
            app.VymsEditField.Value = 15;
            app.VxmsLabel = uilabel(app.VelocityPanel);
            app.VxmsLabel.Text = 'Vx (m/s)';
            app.VxmsEditField = uieditfield(app.VelocityPanel, 'numeric');
            app.VxmsEditField.Limits = [0 50];
            app.VxmsEditField.Value = 30;
            app.VzmsLabel = uilabel(app.VelocityPanel);
            app.VzmsLabel.Text = 'Vz (m/s)';
            app.VzmsEditField = uieditfield(app.VelocityPanel, 'numeric');
            app.VzmsEditField.Limits = [0 50];
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.CHECKEditFieldLabel = uilabel(app.UIFigure);
            app.CHECKEditFieldLabel.Text = 'CHECK';
            app.CHECKEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end