classdef appNew3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Panel                    matlab.ui.container.Panel
        PauseSwitch              matlab.ui.control.RockerSwitch
        PauseSwitchLabel         matlab.ui.control.Label
        Viewangle2Knob           matlab.ui.control.Knob
        Viewangle2KnobLabel      matlab.ui.control.Label
        Viewangle1Knob           matlab.ui.control.Knob
        Viewangle1KnobLabel      matlab.ui.control.Label
        TimesEditField           matlab.ui.control.NumericEditField
        TimesEditFieldLabel      matlab.ui.control.Label
        PlotButton               matlab.ui.control.Button
        VelocityPanel            matlab.ui.container.Panel
        VzmsEditField            matlab.ui.control.NumericEditField
        VzmsLabel                matlab.ui.control.Label
        VxmsEditField            matlab.ui.control.NumericEditField
        VxmsLabel                matlab.ui.control.Label
        VymsEditField            matlab.ui.control.NumericEditField
        VymsLabel                matlab.ui.control.Label
        OmegaPanel               matlab.ui.container.Panel
        ValuerotationsEditField  matlab.ui.control.NumericEditField
        ValueradsLabel           matlab.ui.control.Label
        PhidegreeEditField       matlab.ui.control.NumericEditField
        PhidegreeLabel           matlab.ui.control.Label
        ThetadegreeEditField     matlab.ui.control.NumericEditField
        ThetadegreeLabel         matlab.ui.control.Label
        TimePanel                matlab.ui.container.Panel
        StepsEditField           matlab.ui.control.NumericEditField
        StepsEditFieldLabel      matlab.ui.control.Label
        EndsEditField            matlab.ui.control.NumericEditField
        EndsEditFieldLabel       matlab.ui.control.Label
        PausemsEditField         matlab.ui.control.NumericEditField
        BeginsLabel              matlab.ui.control.Label
        UIAxes_2                 matlab.ui.control.UIAxes
        UIAxes                   matlab.ui.control.UIAxes
    end

   
    properties (Access = public)
        counter=1;
        Limit=[0 0 0 0 0 0];
        ViewAngle1;
        ViewAngle2;
        Xpos;
        Ypos;
        Zpos;
        omega_x;
        omega_y;
        omega_z;
        Vx;
        Vy;
        Vz;
        x0 = 0;
        y0 = 0;
        z0 = 1.681;
        Xball;
        Yball;
        Zball;
        rball=0.037;
        BallTheta = linspace(0,pi,10);
        BallPhi = linspace(0,2*pi,10);
        SurfaceZ=zeros(2);
        StrikeZoneY=18.44;
        StrikeZoneX=linspace(-0.2159,0.2159,2);
        StrikeZoneZ=linspace(0.5,1.6,2);
        timeBegin = 0;
        Fx;
        Fy;
        Fz;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            %path
            vx0 = app.VxmsEditField.Value;
            vy0 = app.VymsEditField.Value;
            vz0 = app.VzmsEditField.Value;
            xyz0 = [app.x0;vx0;app.y0;vy0;app.z0;vz0];
            m=0.144;
            omega = app.ValuerotationsEditField.Value;
            theta = app.ThetadegreeEditField.Value;
            phi = app.PhidegreeEditField.Value;
           
            app.omega_x = omega * sind(theta) * cosd(phi);
            app.omega_y = omega * sind(theta) * sind(phi);
            app.omega_z = omega * cosd(theta);
          
            timeEnd = app.EndsEditField.Value;
            timeStep = app.StepsEditField.Value;
            times = app.timeBegin:timeStep:timeEnd;
            colorstring = 'rbcmyk';

            %Two plots
            ax = app.UIAxes;
            ax2 = app.UIAxes_2;
            %Draw a ball
            [Theta, Phi ] = meshgrid(app.BallTheta,app.BallPhi);

            if (app.counter<2)
            app.Xball = app.rball.*sin(Theta).*cos(Phi);
            app.Yball = app.rball.*sin(Theta).*sin(Phi);
            app.Zball = app.rball.*cos(Theta);
            daspect(ax,[1 1 1]);
            daspect(ax2,[1 1 1]);
            end
            omega_arr = [app.omega_x app.omega_y app.omega_z]; 
            %Solve ode
           [t, xyz] = ode45(@(t,xyz)NewMagnusApp(t,xyz,omega_arr,m, omega, v), times, xyz0);

            %The index that the hit the ground
            Maxindex = find(xyz(:,5) < app.rball, 1, 'first');

            %The ball can't touch the groudn, use all index
            if isempty(Maxindex)
                Maxindex=timeEnd/timeStep+1;
            end
            app.Xpos=xyz(1:Maxindex,1);
            app.Ypos=xyz(1:Maxindex,3);
            app.Zpos=xyz(1:Maxindex,5);
            app.Vx=xyz(1:Maxindex,2);
            app.Vy=xyz(1:Maxindex,4);
            app.Vz=xyz(1:Maxindex,6);
            app.Fx=m*diff(xyz(:,2),1)/timeStep;
            app.Fy=m*diff(xyz(:,4),1)/timeStep;
            app.Fz=m*diff(xyz(:,6),1)/timeStep;

            
           %Set a limit to the plots
           for i=1:6
                if mod(i,2)==0
                    if max(xyz(1:Maxindex,i-1))+5>app.Limit(i)
                        app.Limit(i)=max(xyz(1:Maxindex,i-1))+5;
                    end
                else
                    if min(xyz(1:Maxindex,i))-5<app.Limit(i)
                        app.Limit(i)=min(xyz(1:Maxindex,i))-5;  
                    end
                end
            end
            set(ax,'XLim',[app.Limit(1) app.Limit(2)],... 
            'YLim', [app.Limit(3) app.Limit(4)],...
            'ZLim', [0 app.Limit(6)])
            set(ax2,'XLim',[app.Limit(1) app.Limit(2)],... 
            'YLim', [app.Limit(3) app.Limit(4)],...
            'ZLim', [0 app.Limit(6)])

            %Draw a ground
            SurfaceX=linspace(app.Limit(1),app.Limit(2),2);
            SurfaceY=linspace(app.Limit(3),app.Limit(4),2);
            %Draw Strike Zone
            [SZX, SZZ] = meshgrid(app.StrikeZoneX, app.StrikeZoneZ);
            SZY = app.StrikeZoneY * ones(2);


            surface(ax,SurfaceX,SurfaceY,app.SurfaceZ,'FaceColor','g','edgecolor','none');
           surface(ax,SZX, SZY, SZZ, 'FaceColor', 'w');
           

            curve = animatedline(ax, 'LineWidth',2, 'Marker', '.', 'Color', colorstring(app.counter));
            
            %Ploting
            for i=1:Maxindex
                %when the pause switch is on
                while (app.PauseSwitch.Value =="On")
                    pause(0.5);
                    app.ViewAngle1=app.Viewangle1Knob.Value;
                    app.ViewAngle2=app.Viewangle2Knob.Value;
                    view(ax, [app.ViewAngle1 app.ViewAngle2]);
                    view(ax2, [app.ViewAngle1 app.ViewAngle2]);
                end
                
                addpoints(curve, app.Xpos(i), app.Ypos(i), app.Zpos(i))
                app.TimesEditField.Value=(i-1)*timeStep;
                drawnow
                hold(ax,"on");
                
                if (mod(i,4)==0)
                hold (ax2,"off");
                surf(ax2,app.Xball+app.Xpos(i),app.Yball+app.Ypos(i),app.Zball+app.Zpos(i));
                hold (ax2,"on");
                plot3(ax2,[app.Xpos(i) ,app.Xpos(i)+app.Vx(i)] ,[app.Ypos(i) ,app.Ypos(i)+app.Vy(i)], ...
                    [app.Zpos(i) ,app.Zpos(i)+app.Vz(i)],'r','LineWidth',0.5);
                plot3(ax2,[app.Xpos(i) ,app.Xpos(i)+app.omega_x] ,[app.Ypos(i) ,app.Ypos(i)+app.omega_y], ...
                    [app.Zpos(i) ,app.Zpos(i)+app.omega_z],'b','LineWidth',0.5);
                plot3(ax2,[app.Xpos(i) ,app.Xpos(i)+app.Fx(i)] ,[app.Ypos(i) ,app.Ypos(i)+app.Fy(i)], ...
                    [app.Zpos(i) ,app.Fz(i)],'k','LineWidth',0.5);


                end
                    app.ViewAngle1=app.Viewangle1Knob.Value;
                    app.ViewAngle2=app.Viewangle2Knob.Value;
                view(ax, [app.ViewAngle1 app.ViewAngle2]);
                view(ax2, [app.ViewAngle1 app.ViewAngle2]);
                pause(app.PausemsEditField.Value/1000);
            end
            app.counter= mod(app.counter,6)+1;

while(true)
    pause(0.5);
    app.ViewAngle1=app.Viewangle1Knob.Value;
    app.ViewAngle2=app.Viewangle2Knob.Value;
    view(ax, [app.ViewAngle1 app.ViewAngle2]);
    view(ax2, [app.ViewAngle1 app.ViewAngle2]);
end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Trajactory of ball')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Trajactory of ball')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.TimePanel = uipanel(app.UIFigure);
            app.TimePanel.Title = 'Time';
            app.BeginsLabel = uilabel(app.TimePanel);
            app.BeginsLabel.Text = 'Pause(ms)';
            app.PausemsEditField = uieditfield(app.TimePanel, 'numeric');
            app.PausemsEditField.Limits = [0 5000];
            app.PausemsEditField.Value = 50;
            app.EndsEditFieldLabel = uilabel(app.TimePanel);
            app.EndsEditFieldLabel.Text = {'End (s)'; ''};
            app.EndsEditField = uieditfield(app.TimePanel, 'numeric');
            app.EndsEditField.Limits = [0 50];
            app.EndsEditField.Value = 0.5;
            app.StepsEditFieldLabel = uilabel(app.TimePanel);
            app.StepsEditFieldLabel.Text = {'Step (s)'; ''};
            app.StepsEditField = uieditfield(app.TimePanel, 'numeric');
            app.StepsEditField.Limits = [0 50];
            app.StepsEditField.Value = 0.02;
            app.OmegaPanel = uipanel(app.UIFigure);
            app.OmegaPanel.Title = 'Omega';
            app.ThetadegreeLabel = uilabel(app.OmegaPanel);
            app.ThetadegreeLabel.Text = 'Theta (degree)';
            app.ThetadegreeEditField = uieditfield(app.OmegaPanel, 'numeric');
            app.ThetadegreeEditField.Limits = [0 180];
            app.ThetadegreeEditField.Value = 45;
            app.PhidegreeLabel = uilabel(app.OmegaPanel);
            app.PhidegreeLabel.Text = 'Phi (degree)';
            app.PhidegreeEditField = uieditfield(app.OmegaPanel, 'numeric');
            app.PhidegreeEditField.Limits = [0 360];
            app.ValueradsLabel = uilabel(app.OmegaPanel);
            app.ValueradsLabel.Text = 'Value (rotation/s)';
            app.ValuerotationsEditField = uieditfield(app.OmegaPanel, 'numeric');
            app.ValuerotationsEditField.Limits = [0 500];
            app.ValuerotationsEditField.Value = 37;
            app.VelocityPanel = uipanel(app.UIFigure);
            app.VelocityPanel.Title = 'Velocity';
            app.VymsLabel = uilabel(app.VelocityPanel);
            app.VymsLabel.Text = 'Vy (m/s)';
            app.VymsEditField = uieditfield(app.VelocityPanel, 'numeric');
            app.VymsEditField.Limits = [-50 50];
            app.VymsEditField.Value = 43.5;
            app.VxmsLabel = uilabel(app.VelocityPanel);
            app.VxmsLabel.Text = 'Vx (m/s)';
            app.VxmsEditField = uieditfield(app.VelocityPanel, 'numeric');
            app.VxmsEditField.Limits = [-50 50];
            app.VzmsLabel = uilabel(app.VelocityPanel);
            app.VzmsLabel.Text = 'Vz (m/s)';
            app.VzmsEditField = uieditfield(app.VelocityPanel, 'numeric');
            app.VzmsEditField.Limits = [-50 50];
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.TimesEditFieldLabel = uilabel(app.UIFigure);
            app.TimesEditFieldLabel.Text = 'Time(s)';
            app.TimesEditField = uieditfield(app.UIFigure, 'numeric');
            app.TimesEditField.Editable = 'off';
            app.Panel = uipanel(app.UIFigure);
            app.Viewangle1KnobLabel = uilabel(app.Panel);
            app.Viewangle1KnobLabel.Text = 'View angle 1';
            app.Viewangle1Knob = uiknob(app.Panel, 'continuous');
            app.Viewangle1Knob.Limits = [-180 180];
            app.Viewangle1Knob.Value = -20;
            app.Viewangle2KnobLabel = uilabel(app.Panel);
            app.Viewangle2KnobLabel.Text = 'View angle 2';
            app.Viewangle2Knob = uiknob(app.Panel, 'continuous');
            app.Viewangle2Knob.Limits = [-180 180];
            app.Viewangle2Knob.Value = 18;
            app.PauseSwitchLabel = uilabel(app.Panel);
            app.PauseSwitchLabel.Text = 'Pause';
            app.PauseSwitch = uiswitch(app.Panel, 'rocker');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appNew3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end